classdef BiGGViz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        HTMLViewer                      matlab.ui.control.HTML
        LeftPanel                       matlab.ui.container.Panel
        NeighborhoodModeDropDown        matlab.ui.control.DropDown
        NeighborhoodModeDropDownLabel   matlab.ui.control.Label
        HighlightPathwayDropDown        matlab.ui.control.DropDown
        HighlightPathwayDropDownLabel   matlab.ui.control.Label
        NodeKeyDropDown                 matlab.ui.control.DropDown
        SelectNodeKeyDropDownLabel      matlab.ui.control.Label
        BacktoFullNetworkButton         matlab.ui.control.Button
        ShowNeighborhoodButton          matlab.ui.control.Button
        NeighborhoodDepthDropDown       matlab.ui.control.DropDown
        NeighborhoodDepthDropDownLabel  matlab.ui.control.Label
        NodeKeyField                    matlab.ui.control.EditField
        TypeNodeKeyLabel                matlab.ui.control.Label
        LabelByDropDown                 matlab.ui.control.DropDown
        NetworkTypeButtonGroup          matlab.ui.container.ButtonGroup
        MetNetworkButton                matlab.ui.control.ToggleButton
        RxnNetworkButton                matlab.ui.control.ToggleButton
        ResetFiltersButton              matlab.ui.control.Button
        ApplyFiltersButton              matlab.ui.control.Button
        MinCompSizeField                matlab.ui.control.NumericEditField
        MinComponentSizeEditFieldLabel  matlab.ui.control.Label
        MaxDegreeField                  matlab.ui.control.NumericEditField
        MaxDegree0offEditFieldLabel     matlab.ui.control.Label
        LabelByDropDownLabel            matlab.ui.control.Label
        SizeDropDown                    matlab.ui.control.DropDown
        ColorDropDown                   matlab.ui.control.DropDown
        ColorByDropDownLabel            matlab.ui.control.Label
        SizeByDropDownLabel             matlab.ui.control.Label
        LoadModelButton                 matlab.ui.control.Button
        RightAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        G_main       % Store the graph object
        annoTable    % Store the active metadata table used for rendering
        G_full           % Full graph for reset / neighborhood extraction
        annoTableFull    % Full metadata table for reset / neighborhood extraction
        fileAnnoTable    % Store parsed annotation-file metadata
        nodeDisplayMap   % maps dropdown display text -> true node key
        tempHTMLPath % Store the path to the generated HTML file
        model        % Store the loaded COBRA model
        fbaTable     % Store the FBA result

        lastCapturePDFRequest string = ""   % Prevent duplicate handling of same uihtml Data message
        lastCaptureHTMLRequest string = ""
        lastCaptureCytoscapeRequest string = ""
    end

    methods (Access = private)

        function exportCytoscapeExcel(app)

            if isempty(app.G_main) || isempty(app.annoTable)
                uialert(app.UIFigure, ...
                    'No network to export. Load a model first.', ...
                    'No Data');
                return;
            end

            [filename, pathname] = uiputfile('*.xlsx', ...
                'Export Cytoscape Tables', ...
                sprintf('BiGGViz_Cytoscape_%s.xlsx', datestr(now, 'yyyymmdd_HHMMSS')));

            if isequal(filename, 0)
                disp("Cytoscape export canceled by user.");
                return;
            end

            destFile = fullfile(pathname, filename);

            app.UIFigure.Pointer = 'watch';
            drawnow limitrate;

            try
                % ------------------------------------------------------------
                % 1. Node table
                % ------------------------------------------------------------
                nodeIDs = string(app.G_main.Nodes.Name);

                nodesTable = app.annoTable;
                keyColName = string(nodesTable.Properties.VariableNames{1});

                % Keep only nodes currently visible in the displayed graph.
                keepMask = ismember(string(nodesTable.(keyColName)), nodeIDs);
                nodesTable = nodesTable(keepMask, :);

                % Reorder rows to match the current graph node order.
                [~, loc] = ismember(nodeIDs, string(nodesTable.(keyColName)));
                validNodeMask = loc > 0;
                nodesTable = nodesTable(loc(validNodeMask), :);

                % Cytoscape commonly expects an ID-like node key column.
                % Put "id" first, while keeping the original key column too.
                nodeKeyValues = string(nodesTable.(keyColName));
                id = nodeKeyValues;

                % Add display name if possible.
                if ismember("Name", string(nodesTable.Properties.VariableNames))
                    name = string(nodesTable.Name);
                else
                    name = id;
                end

                cytoscapeNodeBase = table(id, name, ...
                    'VariableNames', {'id', 'name'});

                % Avoid duplicate id/name columns if annotation already has similar names.
                annoForNodes = nodesTable;
                annoVarNames = string(annoForNodes.Properties.VariableNames);

                removeVars = annoVarNames(ismember(lower(annoVarNames), ["id", "name"]));
                if ~isempty(removeVars)
                    annoForNodes = removevars(annoForNodes, cellstr(removeVars));
                end

                nodesTableOut = [cytoscapeNodeBase, annoForNodes];

                % ------------------------------------------------------------
                % 2. Edge table
                % ------------------------------------------------------------
                ends = app.G_main.Edges.EndNodes;

                Source = string(ends(:, 1));
                Target = string(ends(:, 2));

                % Cytoscape accepts source/target-style edge tables.
                source = Source;
                target = Target;
                interaction = repmat("interacts_with", numel(source), 1);

                edge_id = source + " (interacts_with) " + target;

                edgesTableOut = table(edge_id, source, target, interaction, ...
                    'VariableNames', {'id', 'source', 'target', 'interaction'});

                % Include edge metadata if your graph has any extra edge variables.
                edgeVars = string(app.G_main.Edges.Properties.VariableNames);
                extraEdgeVars = setdiff(edgeVars, "EndNodes", 'stable');

                if ~isempty(extraEdgeVars)
                    edgeAnno = app.G_main.Edges(:, cellstr(extraEdgeVars));
                    edgesTableOut = [edgesTableOut, edgeAnno];
                end

                % ------------------------------------------------------------
                % 3. Write Excel workbook
                % ------------------------------------------------------------
                if isfile(destFile)
                    delete(destFile);
                end

                writetable(nodesTableOut, destFile, 'Sheet', 'Nodes');
                writetable(edgesTableOut, destFile, 'Sheet', 'Edges');

                uialert(app.UIFigure, ...
                    sprintf(['Successfully exported Cytoscape-ready tables to:\n%s\n\n' ...
                    'Sheets:\n- Nodes\n- Edges'], destFile), ...
                    'Cytoscape Export Successful');

            catch ME
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
                uialert(app.UIFigure, ...
                    sprintf('Cytoscape export failed:\n%s', ME.message), ...
                    'Cytoscape Export Error');
                app.UIFigure.Pointer = 'arrow';
            end

        end

        function saveInteractiveHTMLFromCaptureView(app)

            disp("saveInteractiveHTMLFromCaptureView called.");

            if isempty(app.G_main) || isempty(app.annoTable)
                uialert(app.UIFigure, ...
                    'No network is available. Load a model first.', ...
                    'No Data');
                return;
            end

            if isempty(app.tempHTMLPath)
                app.tempHTMLPath = fullfile(tempdir, 'BiGGViz_Interactive.html');
            end

            [filename, pathname] = uiputfile('*.html', ...
                'Save Interactive HTML', ...
                sprintf('BiGGViz_Interactive_%s.html', datestr(now, 'yyyymmdd_HHMMSS')));

            if isequal(filename, 0)
                disp("Interactive HTML export canceled by user.");
                return;
            end

            destFile = fullfile(pathname, filename);

            app.UIFigure.Pointer = 'watch';
            drawnow limitrate;

            try
                % Write a fresh standalone interactive HTML file to a separate temp path.
                % Do not overwrite app.tempHTMLPath while the app is currently displaying it.
                tmpExportHTML = fullfile(tempdir, ...
                    sprintf('BiGGViz_Interactive_Export_%s.html', datestr(now, 'yyyymmdd_HHMMSSFFF')));

                labelColumn = getSelectedLabelColumn(app);
                app.annoTable = ensureLabelColumn(app, app.annoTable, labelColumn);
                writeNetworkHTML(app.G_main, app.annoTable, tmpExportHTML, labelColumn, false);

                if ~isfile(tmpExportHTML)
                    error('Temporary interactive HTML file was not created.');
                end

                copyfile(tmpExportHTML, destFile);

                uialert(app.UIFigure, ...
                    sprintf('Exported interactive HTML to:\n%s', destFile), ...
                    'HTML Export Successful');

            catch ME
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
                uialert(app.UIFigure, ...
                    sprintf('Interactive HTML export failed:\n%s', ME.message), ...
                    'HTML Export Error');
            end

            app.UIFigure.Pointer = 'arrow';

        end

        function HTMLViewerDataChanged(app)

            disp("HTMLViewerDataChanged fired.");

            d = app.HTMLViewer.Data;

            disp("Raw HTMLViewer.Data:");
            disp(d);

            if isempty(d)
                disp("HTMLViewer.Data is empty. Returning.");
                return;
            end

            d = string(d);

            if strlength(d) == 0
                disp("HTMLViewer.Data is blank. Returning.");
                return;
            end


            % Capture View: Cytoscape table export ====================

            if startsWith(d, "CAPTURE_VIEW_CYTOSCAPE::")

                disp("Capture View Cytoscape export request received from HTMLViewer.");

                % Prevent duplicate save dialogs for the same Cytoscape request.
                if d == app.lastCaptureCytoscapeRequest
                    disp("Duplicate Capture View Cytoscape request ignored.");
                    app.HTMLViewer.Data = "";
                    return;
                end

                app.lastCaptureCytoscapeRequest = d;

                % Clear immediately so the next real click can trigger DataChangedFcn.
                app.HTMLViewer.Data = "";

                try
                    exportCytoscapeExcel(app);
                catch ME
                    disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
                    uialert(app.UIFigure, ...
                        sprintf('Cytoscape export failed:\n%s', ME.message), ...
                        'Cytoscape Export Error');
                end

                return;
            end

            % Capture View: Interactive HTML export ===================

            if startsWith(d, "CAPTURE_VIEW_HTML::")

                disp("Capture View HTML request received from HTMLViewer.");

                % Prevent duplicate save dialogs for the same HTML request.
                if d == app.lastCaptureHTMLRequest
                    disp("Duplicate Capture View HTML request ignored.");
                    app.HTMLViewer.Data = "";
                    return;
                end

                app.lastCaptureHTMLRequest = d;

                % Clear immediately so the next real click can trigger DataChangedFcn.
                app.HTMLViewer.Data = "";

                try
                    saveInteractiveHTMLFromCaptureView(app);
                catch ME
                    disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
                    uialert(app.UIFigure, ...
                        sprintf('Interactive HTML export failed:\n%s', ME.message), ...
                        'HTML Export Error');
                end

                return;
            end

            % Capture View: PDF export ================================

            if ~startsWith(d, "CAPTURE_VIEW_PDF::")
                disp("HTMLViewer.Data does not contain a recognized Capture View request. Returning.");
                app.HTMLViewer.Data = "";
                return;
            end

            % Prevent duplicate save windows for the same PDF request.
            if d == app.lastCapturePDFRequest
                disp("Duplicate Capture View PDF request ignored.");
                app.HTMLViewer.Data = "";
                return;
            end

            app.lastCapturePDFRequest = d;

            disp("Capture View PDF request received from HTMLViewer.");

            % Expected format:
            % CAPTURE_VIEW_PDF::<timestamp>::<url-encoded SVG>
            payload = extractAfter(d, "CAPTURE_VIEW_PDF::");
            parts = split(payload, "::");

            if numel(parts) < 2
                uialert(app.UIFigure, ...
                    'Malformed Capture View PDF request received from HTML viewer.', ...
                    'PDF Export Error');
                return;
            end

            encodedSVG = strjoin(parts(2:end), "::");

            try
                svgText = char(matlab.net.URI.decode(encodedSVG));
            catch
                % Fallback for older MATLAB versions if matlab.net.URI.decode has issues
                svgText = char(encodedSVG);
                svgText = strrep(svgText, '%3C', '<');
                svgText = strrep(svgText, '%3E', '>');
                svgText = strrep(svgText, '%22', '"');
                svgText = strrep(svgText, '%20', ' ');
                svgText = strrep(svgText, '%0A', newline);
                svgText = strrep(svgText, '%2F', '/');
                svgText = strrep(svgText, '%3D', '=');
                svgText = strrep(svgText, '%3A', ':');
                svgText = strrep(svgText, '%3B', ';');
                svgText = strrep(svgText, '%23', '#');
                svgText = strrep(svgText, '%3F', '?');
            end

            % Remove XML declaration. It is unnecessary inside the temporary HTML body,
            % and avoids printing encoded/declaration text into the PDF.
            svgText = regexprep(svgText, '^\s*<\?xml[^>]*\?>\s*', '');
            svgText = regexprep(svgText, '^\s*<%3Fxml[^>]*%3F>\s*', '');

            if isempty(svgText) || ~contains(string(svgText), "<svg")
                uialert(app.UIFigure, ...
                    'The received Capture View SVG appears to be empty or invalid.', ...
                    'PDF Export Error');
                return;
            end

            [filename, pathname] = uiputfile('*.pdf', ...
                'Save Capture View as PDF', ...
                sprintf('BiGGViz_CaptureView_%s.pdf', datestr(now, 'yyyymmdd_HHMMSS')));

            if isequal(filename, 0)
                app.HTMLViewer.Data = "";
                return;
            end

            destFile = fullfile(pathname, filename);

            app.UIFigure.Pointer = 'watch';
            drawnow limitrate;

            try
                chromePaths = {
                    'C:\Program Files\Google\Chrome\Application\chrome.exe'
                    'C:\Program Files (x86)\Google\Chrome\Application\chrome.exe'
                    fullfile(getenv('LOCALAPPDATA'), 'Google\Chrome\Application\chrome.exe')
                    fullfile(getenv('PROGRAMFILES'), 'Google\Chrome\Application\chrome.exe')
                    };

                chromeBin = '';
                for i = 1:numel(chromePaths)
                    if isfile(chromePaths{i})
                        chromeBin = chromePaths{i};
                        break;
                    end
                end

                if isempty(chromeBin)
                    error('Chrome not found. Please install Google Chrome or update the Chrome path list.');
                end

                tmpHTML = fullfile(tempdir, 'BiGGViz_CaptureView_SVG.html');
                tmpPDF  = fullfile(tempdir, 'BiGGViz_CaptureView.pdf');
                tmpDir  = fullfile(tempdir, 'chrome_headless_capture_tmp');

                if isfile(tmpPDF)
                    delete(tmpPDF);
                end

                fid = fopen(tmpHTML, 'w');
                if fid < 0
                    error('Could not create temporary HTML file for PDF export.');
                end

                fprintf(fid, '<!doctype html>\n');
                fprintf(fid, '<html><head><meta charset="utf-8">\n');
                fprintf(fid, '<style>\n');
                fprintf(fid, 'html,body{margin:0;padding:0;background:#fbfbfb;}\n');
                fprintf(fid, 'svg{display:block;}\n');
                fprintf(fid, '</style>\n');
                fprintf(fid, '</head><body>\n');
                fprintf(fid, '%s\n', svgText);
                fprintf(fid, '</body></html>\n');
                fclose(fid);

                htmlURL = ['file:///', strrep(tmpHTML, '\', '/')];

                cmd = sprintf(['"%s" --headless --disable-gpu ' ...
                    '--run-all-compositor-stages-before-draw ' ...
                    '--virtual-time-budget=3000 ' ...
                    '--print-to-pdf="%s" ' ...
                    '--no-pdf-header-footer ' ...
                    '--user-data-dir="%s" ' ...
                    '"%s"'], ...
                    chromeBin, tmpPDF, tmpDir, htmlURL);

                [status, cmdout] = system(cmd);

                if status == 0 && isfile(tmpPDF)
                    copyfile(tmpPDF, destFile);
                    delete(tmpPDF);

                    uialert(app.UIFigure, ...
                        sprintf('Exported to:\n%s', destFile), ...
                        'PDF Export Successful');
                else
                    error('Chrome failed to create the PDF.\n\n%s', cmdout);
                end

            catch ME
                uialert(app.UIFigure, ...
                    sprintf('PDF export failed:\n%s', ME.message), ...
                    'PDF Export Error');
            end

            app.UIFigure.Pointer = 'arrow';

            % Clear Data so the next click is guaranteed to trigger DataChangedFcn.
            app.HTMLViewer.Data = "";

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. Figure/window setup
            app.UIFigure.Resize = 'off';

            % 2. Hide HTML viewer if needed
            app.HTMLViewer.Visible = 'off';

            % Register callback for messages sent from writeNetworkHTML through matlabComponent.Data
            app.HTMLViewer.DataChangedFcn = @(src, event) HTMLViewerDataChanged(app);

            % Clear stale Data from previous interactions
            app.HTMLViewer.Data = "";

            % Default node controls before a model is loaded
            app.NodeKeyField.Value = 'None';
            app.NodeKeyDropDown.Items = {'None'};
            app.NodeKeyDropDown.Value = 'None';
            app.LabelByDropDown.Items = {'None'};
            app.LabelByDropDown.Value = 'None';

            % 3. Compute right-side axes position
            figPos = app.UIFigure.Position;
            figW = figPos(3);
            figH = figPos(4);

            leftPos = app.LeftPanel.Position;
            leftW = leftPos(3);

            app.RightAxes.Position = [leftW, 1, figW - leftW, figH];

            % 4. REPLACE THE OLD IMAGE BLOCK HERE
            appFolder = fileparts(which('BiGGViz.mlapp'));
            imgPath = fullfile(appFolder, 'assets', 'launch.png');

            [X, map, ~] = imread(imgPath);

            if ~isempty(map)
                img = ind2rgb(X, map);
            else
                img = X;
                if ismatrix(img)
                    img = cat(3, img, img, img);
                end
                if size(img,3) > 3
                    img = img(:,:,1:3);
                end
                if isa(img, 'uint8')
                    img = im2double(img);
                end
            end

            image(app.RightAxes, img);
            app.RightAxes.YDir = 'reverse';
            app.RightAxes.DataAspectRatioMode = 'auto';
            app.RightAxes.PlotBoxAspectRatioMode = 'auto';
            app.RightAxes.XLim = [0.5 size(img,2)+0.5];
            app.RightAxes.YLim = [0.5 size(img,1)+0.5];

            title(app.RightAxes, '')
            xlabel(app.RightAxes, '')
            ylabel(app.RightAxes, '')
            app.RightAxes.Toolbar.Visible = 'off';
            app.RightAxes.Interactions = [];
            app.RightAxes.XTick = [];
            app.RightAxes.YTick = [];
            app.RightAxes.XColor = 'none';
            app.RightAxes.YColor = 'none';
            app.RightAxes.Box = 'off';
            app.RightAxes.Visible = 'on';
            axis(app.RightAxes, 'off');

            bg = reshape(img(1,1,1:3), 1, 3);
            app.UIFigure.Color = bg;
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)

            % 1. Prompt user to select a BiGG model (.mat or .xml)
            [fileName, filePath] = uigetfile({'*.mat;*.xml', 'BiGG Models (*.mat, *.xml)'}, 'Select a Model');
            if isequal(fileName, 0)
                return; % User canceled
            end
            fullPath = fullfile(filePath, fileName);

            % 2. Prompt user for Annotation Excel File (or let them cancel to skip)
            [annoFile, annoPath] = uigetfile({'*.xlsx;*.csv', 'Annotation Files (*.xlsx, *.csv)'}, 'Select Annotation File (Cancel to skip)');

            % Change mouse pointer to a spinning wheel while loading
            app.UIFigure.Pointer = 'watch';
            drawnow;

            try
                % Load the COBRA Model
                app.model = readCbModel(fullPath);
                app.fbaTable = []; % Clear cache so FBA reruns for new model
                disp('LOAD 1: model read');

                % Process Annotations
                if ~isequal(annoFile, 0)
                    app.fileAnnoTable = parseAnnotations(fullfile(annoPath, annoFile));
                    disp("Parsed annotation columns:");
                    disp(string(app.fileAnnoTable.Properties.VariableNames)');
                    disp("First parsed annotation rows:");
                    disp(app.fileAnnoTable(1:min(5,height(app.fileAnnoTable)), :));
                else
                    app.fileAnnoTable = table();
                end
                disp('LOAD 2: annotations parsed');

                % Build the Network based on user selection
                if app.NetworkTypeButtonGroup.SelectedObject == app.RxnNetworkButton
                    % Reaction-Reaction Network
                    app.G_main = buildRxnRxnGraph(app.model);
                    disp('LOAD 3A: reaction graph built');

                    numRxns = length(app.model.rxns);
                    rxnIDs = string(app.model.rxns);
                    rxnNames = string(app.model.rxnNames);

                    pathways = strings(numRxns, 1);
                    if isfield(app.model, 'subSystems') && ~isempty(app.model.subSystems)
                        for i = 1:numRxns
                            sub = app.model.subSystems{i};
                            if isempty(sub)
                                pathways(i) = "Unassigned";
                            elseif iscell(sub)
                                first = strtrim(string(sub{1}));
                                if first == ""
                                    pathways(i) = "Unassigned";
                                else
                                    pathways(i) = first;
                                end
                            else
                                pathways(i) = strtrim(string(sub));
                            end
                        end
                    else
                        pathways(:) = "Unassigned";
                    end

                    formulas = string(printRxnFormula(app.model, app.model.rxns, false));

                    baseAnno = table(rxnIDs, rxnNames, pathways, formulas, ...
                        'VariableNames', {'ReactionKey', 'Name', 'PathwayKey', 'FormulaKey'});

                    % File metadata overrides model metadata where keys match
                    disp("Reaction baseAnno columns before overlay:");
                    disp(string(baseAnno.Properties.VariableNames)');
                    disp("File annotation columns before overlay:");
                    disp(string(app.fileAnnoTable.Properties.VariableNames)');

                    app.annoTable = overlayFileMetadata(app, baseAnno, app.fileAnnoTable);
                    app.annoTable = normalizeNodeSizeColumn(app, app.annoTable);

                    disp("Reaction annoTable columns after overlay:");
                    disp(string(app.annoTable.Properties.VariableNames)');
                    disp('LOAD 4A: reaction annotation table built');

                else
                    % Metabolite-Metabolite Network
                    app.G_main = buildMetMetGraph(app.model);
                    disp('LOAD 3B: metabolite graph built');

                    [~, metIdx] = ismember(string(app.G_main.Nodes.Name), string(app.model.mets));
                    MetaboliteKey = string(app.G_main.Nodes.Name);
                    numMets = numel(MetaboliteKey);
                    Name = string(app.model.metNames(metIdx));

                    % Robust 3-tier compartment parsing
                    Compartment = strings(numMets, 1);
                    if isfield(app.model, 'metComps') && isfield(app.model, 'comps') && isnumeric(app.model.metComps)
                        for i = 1:numMets
                            ci = app.model.metComps(i);
                            if ci > 0 && ci <= length(app.model.comps)
                                Compartment(i) = string(app.model.comps{ci});
                            else
                                Compartment(i) = "Unknown";
                            end
                        end
                    elseif isfield(app.model, 'metComps') && ~isnumeric(app.model.metComps)
                        Compartment = string(app.model.metComps);
                    else
                        for i = 1:numMets
                            id = char(MetaboliteKey(i));
                            tok = regexp(id, '\[([a-zA-Z0-9_]+)\]$', 'tokens', 'once');
                            if isempty(tok)
                                tok = regexp(id, '_([a-zA-Z0-9]+)$', 'tokens', 'once');
                            end
                            if ~isempty(tok)
                                Compartment(i) = string(tok{1});
                            else
                                Compartment(i) = "Unknown";
                            end
                        end
                    end

                    baseAnno = table(MetaboliteKey, Name, Compartment, ...
                        'VariableNames', {'MetaboliteKey', 'Name', 'Compartment'});

                    app.annoTable = overlayFileMetadata(app, baseAnno, app.fileAnnoTable);
                    app.annoTable = normalizeNodeSizeColumn(app, app.annoTable);

                    disp("Metabolite annoTable columns after overlay:");
                    disp(string(app.annoTable.Properties.VariableNames)');
                    disp('LOAD 4B: metabolite annotation table built');
                end

                % Set dropdowns BEFORE computing size/color
                if app.NetworkTypeButtonGroup.SelectedObject == app.RxnNetworkButton
                    app.ColorDropDown.Items = ["Uniform","Pathway","Reversibility","Gene Association","FBA Flux"];
                    app.ColorDropDown.Value = "Uniform";
                    app.SizeDropDown.Items = ["Uniform","FBA Flux","Degree"];
                    app.SizeDropDown.Value = "Uniform";
                    if ismember("NodeSize", string(app.annoTable.Properties.VariableNames))
                        items = string(app.SizeDropDown.Items);
                        if ~ismember("Node Size", items)
                            app.SizeDropDown.Items = [items, "Node Size"];
                        end
                    end

                    app.NeighborhoodModeDropDown.Items = {'Graph Hop', 'Pathway-Restricted Hop'};
                    app.NeighborhoodModeDropDown.Value = 'Graph Hop';
                    app.NeighborhoodModeDropDown.Enable = 'on';
                else
                    app.ColorDropDown.Items = ["Uniform","Compartment","Metabolite Class"];
                    app.ColorDropDown.Value = "Uniform";
                    app.SizeDropDown.Items = ["Uniform","Degree"];
                    app.SizeDropDown.Value = "Uniform";
                    if ismember("NodeSize", string(app.annoTable.Properties.VariableNames))
                        items = string(app.SizeDropDown.Items);
                        if ~ismember("Node Size", items)
                            app.SizeDropDown.Items = [items, "Node Size"];
                        end
                    end

                    app.NeighborhoodModeDropDown.Items = {'Graph Hop'};
                    app.NeighborhoodModeDropDown.Value = 'Graph Hop';
                    app.NeighborhoodModeDropDown.Enable = 'on';
                end
                disp('LOAD 5: color/size dropdowns set');

                % Save full graph/table before rendering
                app.G_full = app.G_main;
                app.annoTableFull = app.annoTable;
                disp('LOAD 6: full graph/table saved');

                % Set temp HTML path
                app.tempHTMLPath = fullfile(tempdir, 'BiGGViz_Interactive.html');
                disp('LOAD 7: temp HTML path set');

                % Populate controls first
                populateNodeKeyDropDown(app);
                disp('LOAD 8: node dropdown populated');

                populatePathwayHighlightDropDown(app);
                disp('LOAD 9: pathway dropdown populated');

                populateLabelByDropDown(app);
                disp('LOAD 9B: label dropdown populated');

                % Now compute color/size
                app.annoTable = computeColorGroups(app, app.ColorDropDown.Value);
                disp('LOAD 10: color computed');

                app.annoTable = computeSizeValues(app, app.SizeDropDown.Value);
                disp('LOAD 11: size computed');

                % Apply optional pathway highlight last
                app.annoTable = applyPathwayHighlight(app, app.annoTable);
                disp('LOAD 12: pathway highlight applied');

                % Update full annotation table after final rendering state is ready
                app.annoTableFull = app.annoTable;
                disp('LOAD 13: annoTableFull updated');

                app.maybeWarnNoPathwayInfo(app.annoTable, app.ColorDropDown.Value);
                disp('LOAD 14: pathway warning check complete');

                if ismember("NodeSize", string(app.annoTable.Properties.VariableNames))
                    disp("NodeSize range: " + num2str(min(app.annoTable.NodeSize)) + ...
                        " to " + num2str(max(app.annoTable.NodeSize)));
                    disp("First 5 NodeSize values:");
                    disp(app.annoTable.NodeSize(1:min(5, height(app.annoTable))));
                else
                    disp("NodeSize column NOT present in annoTable");
                end

                labelColumn = getSelectedLabelColumn(app);
                app.annoTable = ensureLabelColumn(app, app.annoTable, labelColumn);
                writeNetworkHTML(app.G_main, app.annoTable, app.tempHTMLPath, labelColumn)
                disp('LOAD 15: HTML written');

                app.RightAxes.Visible = 'off';
                app.HTMLViewer.Visible = 'on';

                app.HTMLViewer.HTMLSource = '';
                drawnow;
                app.HTMLViewer.HTMLSource = app.tempHTMLPath;
                disp('LOAD 16: HTML viewer updated');

            catch ME
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
                uialert(app.UIFigure, getReport(ME, 'basic', 'hyperlinks', 'off'), 'Error Loading Model');
            end

            % Reset mouse pointer
            app.UIFigure.Pointer = 'arrow';

        end

        % Selection changed function: NetworkTypeButtonGroup
        function NetworkTypeButtonGroupSelectionChanged(app, event)

            % Check if a model has been loaded yet
            if isempty(app.model)
                disp('No model loaded yet. Ignoring toggle.');
                return;
            end

            % Get the name of the newly selected button
            selectedButton = app.NetworkTypeButtonGroup.SelectedObject.Text;
            disp(['Switching view to: ', selectedButton]);

            % Change pointer to watch
            app.UIFigure.Pointer = 'watch';
            drawnow limitrate;

            try
                if strcmp(selectedButton, 'Reaction Network')
                    % 1. Build Reaction Network
                    app.G_main = buildRxnRxnGraph(app.model);

                    % 2. Rebuild Reaction Annotation Table
                    numRxns = length(app.model.rxns);
                    rxnIDs = string(app.model.rxns);
                    rxnNames = string(app.model.rxnNames);

                    % Guard against XML models with no subSystems field
                    pathways = strings(numRxns, 1);
                    if isfield(app.model, 'subSystems') && ~isempty(app.model.subSystems)
                        for i = 1:numRxns
                            sub = app.model.subSystems{i};
                            if isempty(sub)
                                pathways(i) = "Unassigned";
                            elseif iscell(sub)
                                first = strtrim(string(sub{1}));
                                if first == ""
                                    pathways(i) = "Unassigned";
                                else
                                    pathways(i) = first;
                                end
                            else
                                pathways(i) = strtrim(string(sub));
                            end
                        end
                    else
                        pathways(:) = "Unassigned";   % XML fallback
                    end

                    formulas = string(printRxnFormula(app.model, app.model.rxns, false));

                    baseAnno = table(rxnIDs, rxnNames, pathways, formulas, ...
                        'VariableNames', {'ReactionKey', 'Name', 'PathwayKey', 'FormulaKey'});

                    app.annoTable = overlayFileMetadata(app, baseAnno, app.fileAnnoTable);
                    app.annoTable = normalizeNodeSizeColumn(app, app.annoTable);

                    app.ColorDropDown.Items = ["Pathway","Reversibility","Gene Association","FBA Flux"];
                    app.ColorDropDown.Value = "Pathway";
                    app.SizeDropDown.Items  = ["Uniform","FBA Flux","Degree"];
                    app.SizeDropDown.Value  = "Uniform";

                    % If annotations provide a user-defined NodeSize column, expose it
                    if ismember("NodeSize", string(app.annoTable.Properties.VariableNames))
                        items = string(app.SizeDropDown.Items);
                        if ~ismember("Node Size", items)
                            app.SizeDropDown.Items = [items, "Node Size"];
                        end
                    end

                    app.NeighborhoodModeDropDown.Items = {'Graph Hop', 'Pathway-Restricted Hop'};
                    app.NeighborhoodModeDropDown.Value = 'Graph Hop';
                    app.NeighborhoodModeDropDown.Enable = 'on';
                else

                    % 1. Build Metabolite Network
                    app.G_main = buildMetMetGraph(app.model);

                    % 2. Build Metabolite Annotation Table
                    [~, metIdx] = ismember(string(app.G_main.Nodes.Name), string(app.model.mets));

                    MetaboliteKey = string(app.G_main.Nodes.Name);
                    numMets = numel(MetaboliteKey);

                    if isfield(app.model, 'metNames')
                        Name = string(app.model.metNames(metIdx));
                    else
                        Name = MetaboliteKey;
                    end

                    % Robust 3-tier compartment parsing (mirrors exportBiGGViz.m)
                    Compartment = strings(numMets, 1);

                    if isfield(app.model, 'metComps') && isfield(app.model, 'comps') && isnumeric(app.model.metComps)
                        % SBML: metComps are numeric indices into model.comps
                        compIndices = app.model.metComps(metIdx);
                        for i = 1:numMets
                            if compIndices(i) > 0 && compIndices(i) <= length(app.model.comps)
                                Compartment(i) = string(app.model.comps{compIndices(i)});
                            else
                                Compartment(i) = "Unknown";
                            end
                        end

                    elseif isfield(app.model, 'metComps') && ~isnumeric(app.model.metComps)
                        % .mat files: metComps are direct cell strings
                        Compartment = string(app.model.metComps(metIdx));

                    else
                        % Fallback: extract compartment from metabolite ID
                        % handles both 'atp[c]' and 'atp_c' formats
                        for i = 1:numMets
                            id = char(MetaboliteKey(i));
                            tok = regexp(id, '\[([a-zA-Z0-9_]+)\]$', 'tokens', 'once');
                            if isempty(tok)
                                tok = regexp(id, '_([a-zA-Z0-9]+)$', 'tokens', 'once');
                            end
                            if ~isempty(tok)
                                Compartment(i) = string(tok{1});
                            else
                                Compartment(i) = "Unknown";
                            end
                        end
                    end

                    Degree = full(sum(app.model.S(metIdx, :) ~= 0, 2));

                    baseAnno = table(MetaboliteKey, Name, Compartment, Degree, ...
                        'VariableNames', {'MetaboliteKey', 'Name', 'Compartment', 'Degree'});

                    app.annoTable = overlayFileMetadata(app, baseAnno, app.fileAnnoTable);
                    app.annoTable = normalizeNodeSizeColumn(app, app.annoTable);

                    disp("Metabolite annoTable columns after overlay:");
                    disp(string(app.annoTable.Properties.VariableNames)');

                    app.ColorDropDown.Items = ["Uniform", "Compartment","Metabolite Class"];
                    app.ColorDropDown.Value = "Uniform";
                    app.SizeDropDown.Items  = ["Uniform","Degree"];
                    app.SizeDropDown.Value  = "Uniform";

                    % If annotations provide a user-defined NodeSize column, expose it
                    if ismember("NodeSize", string(app.annoTable.Properties.VariableNames))
                        items = string(app.SizeDropDown.Items);
                        if ~ismember("Node Size", items)
                            app.SizeDropDown.Items = [items, "Node Size"];
                        end
                    end

                    app.NeighborhoodModeDropDown.Items = {'Graph Hop'};
                    app.NeighborhoodModeDropDown.Value = 'Graph Hop';
                    app.NeighborhoodModeDropDown.Enable = 'on';
                end

                app.G_full = app.G_main;
                app.annoTableFull = app.annoTable;

                % Refresh available controls first
                populateNodeKeyDropDown(app);
                populatePathwayHighlightDropDown(app);

                populateLabelByDropDown(app);

                % 3. Regenerate HTML
                app.annoTable = computeColorGroups(app, app.ColorDropDown.Value);
                app.annoTable = computeSizeValues(app, app.SizeDropDown.Value);
                app.annoTable = applyPathwayHighlight(app, app.annoTable);

                app.maybeWarnNoPathwayInfo(app.annoTable, app.ColorDropDown.Value);

                app.annoTableFull = app.annoTable;

                % Render
                labelColumn = getSelectedLabelColumn(app);
                app.annoTable = ensureLabelColumn(app, app.annoTable, labelColumn);
                writeNetworkHTML(app.G_main, app.annoTable, app.tempHTMLPath, labelColumn)
                app.HTMLViewer.HTMLSource = '';
                drawnow;
                app.HTMLViewer.HTMLSource = app.tempHTMLPath;

                disp('Network switch complete!');

            catch ME
                disp(['Error: ', ME.message]);
                uialert(app.UIFigure, ME.message, 'Error Switching Network Type');
            end

            % Reset pointer
            app.UIFigure.Pointer = 'arrow';

        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            if isempty(app.model) || isempty(app.G_main)
                return;
            end

            selected = app.ColorDropDown.Value;

            if selected == "FBA Flux"
                app.UIFigure.Pointer = 'watch';
                drawnow limitrate;
                try
                    runFBAIfNeeded(app);
                catch ME
                    uialert(app.UIFigure, ME.message, 'FBA Error');
                    app.UIFigure.Pointer = 'arrow';
                    return;
                end
            end

            if contains(selected, 'TODO')
                uialert(app.UIFigure, [char(selected), ' is not yet implemented. Coming soon!'], 'Coming Soon');
                return;
            end

            app.UIFigure.Pointer = 'watch';
            drawnow limitrate;

            try
                app.annoTable = computeColorGroups(app, selected);
                app.annoTable = computeSizeValues(app, app.SizeDropDown.Value);
                app.annoTable = applyPathwayHighlight(app, app.annoTable);

                disp("Current annoTable columns:");
                disp(app.annoTable.Properties.VariableNames');

                app.maybeWarnNoPathwayInfo(app.annoTable, selected);

                labelColumn = getSelectedLabelColumn(app);
                app.annoTable = ensureLabelColumn(app, app.annoTable, labelColumn);
                writeNetworkHTML(app.G_main, app.annoTable, app.tempHTMLPath, labelColumn)

                app.HTMLViewer.HTMLSource = '';
                drawnow;
                app.HTMLViewer.HTMLSource = app.tempHTMLPath;

            catch ME
                uialert(app.UIFigure, ME.message, 'Error Applying Color');
            end

            app.UIFigure.Pointer = 'arrow';
        end

        function tbl = normalizeNodeSizeColumn(app, tbl)
            vars = string(tbl.Properties.VariableNames);

            % Look for common variations of Node Size
            targetVars = ["Node Size", "nodeSize", "nodesize", "Node_Size", "node_size"];
            for i = 1:numel(targetVars)
                if ismember(targetVars(i), vars) && ~ismember("NodeSize", vars)
                    tbl = renamevars(tbl, targetVars(i), "NodeSize");
                    break;
                end
            end

            % Now verify and convert to numeric
            if ismember("NodeSize", string(tbl.Properties.VariableNames))
                if ~isnumeric(tbl.NodeSize)
                    tbl.NodeSize = str2double(string(tbl.NodeSize));
                else
                    tbl.NodeSize = double(tbl.NodeSize);
                end
                % Ensure bad values become NaN so they don't break sizing
                tbl.NodeSize(~isfinite(tbl.NodeSize) | tbl.NodeSize < 0) = NaN;
            end
        end


        % -----------------------------------------------------------------------------
        function tbl = computeColorGroups(app, selected)

            tbl = app.annoTable;
            nodeList = string(app.G_main.Nodes.Name);
            N = numel(nodeList);
            colorGroups = repmat("Unassigned", N, 1);

            % Remove stale flux-color column unless actively recoloring by FBA flux.
            if selected ~= "FBA Flux"
                if ismember("FluxColor", string(tbl.Properties.VariableNames))
                    tbl.FluxColor = [];
                end
            end

            switch selected

                case 'Uniform'
                    tbl.ColorGroup = repmat("Unassigned", height(tbl), 1);
                    return;

                case "Pathway"
                    if ismember("PathwayKey", string(tbl.Properties.VariableNames))
                        tbl.ColorGroup = tbl.PathwayKey;
                    else
                        tbl.ColorGroup = repmat("Unassigned", height(tbl), 1);
                    end
                    return;

                case "Reversibility"

                    rxnMap = containers.Map(cellstr(string(app.model.rxns)), ...
                        num2cell(1:length(app.model.rxns)));

                    for i = 1:N
                        k = char(nodeList(i));
                        if isKey(rxnMap, k)
                            idx = rxnMap(k);
                            lb = app.model.lb(idx);
                            ub = app.model.ub(idx);

                            if lb < 0 && ub > 0
                                colorGroups(i) = "Reversible";
                            elseif ub <= 0
                                colorGroups(i) = "Reverse Only";
                            else
                                colorGroups(i) = "Irreversible";
                            end
                        end
                    end

                case "Gene Association"

                    rxnMap = containers.Map(cellstr(string(app.model.rxns)), ...
                        num2cell(1:length(app.model.rxns)));

                    for i = 1:N
                        k = char(nodeList(i));

                        if isKey(rxnMap, k)
                            idx = rxnMap(k);
                            hasGene = false;

                            if isfield(app.model,'grRules') && ...
                                    numel(app.model.grRules) >= idx && ...
                                    ~isempty(strtrim(app.model.grRules{idx}))
                                hasGene = true;

                            elseif isfield(app.model,'rules') && ...
                                    numel(app.model.rules) >= idx && ...
                                    ~isempty(strtrim(app.model.rules{idx}))
                                hasGene = true;

                            elseif isfield(app.model,'rxnGeneMat') && ...
                                    size(app.model.rxnGeneMat,1) >= idx && ...
                                    any(app.model.rxnGeneMat(idx,:))
                                hasGene = true;
                            end

                            if hasGene
                                colorGroups(i) = "Gene-Associated";
                            else
                                colorGroups(i) = "Spontaneous / Unknown";
                            end
                        end
                    end

                case "Compartment"

                    if ismember("Compartment", string(tbl.Properties.VariableNames))
                        tbl.ColorGroup = tbl.Compartment;
                    else
                        tbl.ColorGroup = repmat("Unassigned", height(tbl), 1);
                    end
                    return;

                case "Metabolite Class"

                    metMap = containers.Map(cellstr(string(app.model.mets)), ...
                        num2cell(1:length(app.model.mets)));

                    patterns = { ...
                        'atp|adp|amp|nad|nadh|nadp|nadph|fad|fadh|gtp|gdp|coa', 'Cofactor / Energy carrier'; ...
                        'ala|arg|asn|asp|cys|gln|glu|gly|his|ile|leu|lys|met|phe|pro|ser|thr|trp|tyr|val', 'Amino acid'; ...
                        'glc|fru|gal|man|rib|xyl|suc|lac|pyr|pep|mal|fum|cit|oaa', 'Sugar / Organic acid'; ...
                        'pc_|pe_|pg_|ps_|pi_|fa_|acyl|chol|lipid', 'Lipid'; ...
                        'h2o|co2|o2|nh4|pi|ppi|h_', 'Inorganic / Ion' ...
                        };

                    for i = 1:N
                        k = char(nodeList(i));

                        if isKey(metMap, k)
                            idx = metMap(k);
                            metID = lower(k);
                            metName = "";

                            if isfield(app.model,'metNames') && numel(app.model.metNames) >= idx
                                metName = lower(app.model.metNames{idx});
                            end

                            matched = false;

                            for p = 1:size(patterns,1)
                                if ~isempty(regexp(metID, patterns{p,1}, 'once')) || ...
                                        ~isempty(regexp(metName, patterns{p,1}, 'once'))
                                    colorGroups(i) = patterns{p,2};
                                    matched = true;
                                    break;
                                end
                            end

                            if ~matched
                                colorGroups(i) = "Other";
                            end
                        end
                    end

                case 'FBA Flux'

                    if ismember("FBAFlux", string(tbl.Properties.VariableNames))
                        keyColName = char(tbl.Properties.VariableNames{1});
                        cleanFluxKeys = strtrim(string(tbl.(keyColName)));
                        fluxMap = containers.Map(cellstr(cleanFluxKeys), num2cell(tbl.FBAFlux));
                    else
                        fba = runFBAIfNeeded(app);
                        cleanFluxKeys = strtrim(string(fba.ReactionKey));

                        fbaVars = string(fba.Properties.VariableNames);
                        if ismember("Flux", fbaVars)
                            fbaVals = fba.Flux;
                        elseif ismember("FBAFlux", fbaVars)
                            fbaVals = fba.FBAFlux;
                        else
                            fbaVals = fba{:, 2};
                        end

                        fluxMap = containers.Map(cellstr(cleanFluxKeys), num2cell(fbaVals));
                    end

                    keyColName = char(tbl.Properties.VariableNames{1});
                    cleanTblKeys = strtrim(string(tbl.(keyColName)));
                    tblMap = containers.Map(cellstr(cleanTblKeys), num2cell(1:height(tbl)));

                    fullFlux = NaN(height(tbl), 1);
                    fullColorGroup = repmat("No Flux", height(tbl), 1);

                    for i = 1:N
                        k = char(strtrim(nodeList(i)));

                        if isKey(fluxMap, k) && isKey(tblMap, k)
                            rowIdx = tblMap(k);
                            f = fluxMap(k);

                            if ~isnan(f) && isfinite(f)
                                fullFlux(rowIdx) = f;

                                if f > 0
                                    fullColorGroup(rowIdx) = "Positive Flux";
                                elseif f < 0
                                    fullColorGroup(rowIdx) = "Negative Flux";
                                else
                                    fullColorGroup(rowIdx) = "Zero Flux";
                                end
                            end
                        end
                    end

                    % Store both plotting color and real annotation value.
                    tbl.FluxColor = fullFlux;
                    tbl.FBAFlux = fullFlux;
                    tbl.ColorGroup = fullColorGroup;
                    return;

            end

            % ------------------------------------------------------------
            % Map node-level colorGroups back into full annotation table.
            % Used by Reversibility, Gene Association, and Metabolite Class.
            % ------------------------------------------------------------
            fullColor = repmat("Unassigned", height(tbl), 1);
            keyColName = char(tbl.Properties.VariableNames{1});
            tblKeys = string(tbl.(keyColName));
            tblMap = containers.Map(cellstr(tblKeys), num2cell(1:height(tbl)));

            for i = 1:N
                k = char(nodeList(i));
                if isKey(tblMap, k)
                    fullColor(tblMap(k)) = colorGroups(i);
                end
            end

            tbl.ColorGroup = fullColor;

            % ------------------------------------------------------------
            % Store selected computed annotation as a real tooltip/export column.
            % ------------------------------------------------------------
            switch selected
                case "Reversibility"
                    tbl.Reversibility = fullColor;

                case "Gene Association"
                    tbl.GeneAssociation = fullColor;

                case "Metabolite Class"
                    tbl.MetaboliteClass = fullColor;
            end

        end

        % -----------------------------------------------------------------------------
        function tbl = computeSizeValues(app, selected)
            tbl = app.annoTable;
            nodeList = string(app.G_main.Nodes.Name);
            N = numel(nodeList);

            % ONLY clear the plotting Size column. Do NOT delete metadata like FBAFlux!
            if ismember("Size", string(tbl.Properties.VariableNames))
                tbl.Size = [];
            end

            switch selected
                case 'Uniform'
                    % Do nothing. No Size means writeNetworkHTML defaults to fixed radius.
                    return;

                case 'FBA Flux'
                    if ismember("FBAFlux", string(tbl.Properties.VariableNames))
                        % Use user-supplied flux from the annotation file
                        keyColName = char(tbl.Properties.VariableNames{1});
                        cleanFluxKeys = strtrim(string(tbl.(keyColName)));
                        fluxMap = containers.Map(cellstr(cleanFluxKeys), num2cell(tbl.FBAFlux));
                    else
                        % Default to COBRA toolbox FBA calculation
                        fba = runFBAIfNeeded(app);
                        cleanFluxKeys = strtrim(string(fba.ReactionKey));

                        % SAFELY get the flux column
                        fbaVars = string(fba.Properties.VariableNames);
                        if ismember("Flux", fbaVars)
                            fbaVals = fba.Flux;
                        elseif ismember("FBAFlux", fbaVars)
                            fbaVals = fba.FBAFlux;
                        else
                            fbaVals = fba{:, 2}; % Fallback
                        end
                        fluxMap = containers.Map(cellstr(cleanFluxKeys), num2cell(fbaVals));
                    end

                    % Save FBAFlux into the table so it shows in the tooltip
                    keyColName = char(tbl.Properties.VariableNames{1});
                    cleanTblKeys = strtrim(string(tbl.(keyColName)));
                    tblMap = containers.Map(cellstr(cleanTblKeys), num2cell(1:height(tbl)));

                    fullFBA = NaN(height(tbl), 1);
                    for i = 1:N
                        k = char(strtrim(nodeList(i)));
                        if isKey(fluxMap, k) && isKey(tblMap, k)
                            fullFBA(tblMap(k)) = fluxMap(k);
                        end
                    end
                    tbl.FBAFlux = fullFBA;

                    % Now compute the absolute value EXCLUSIVELY for the physical radius (Size)
                    fullSize = zeros(height(tbl), 1);
                    for i = 1:N
                        k = char(strtrim(nodeList(i)));
                        if isKey(fluxMap, k) && isKey(tblMap, k)
                            f = fluxMap(k);
                            if ~isnan(f) && isfinite(f)
                                fullSize(tblMap(k)) = abs(f);
                            end
                        end
                    end
                    tbl.Size = fullSize;

                case 'Degree'
                    if isa(app.G_main, 'digraph')
                        deg = indegree(app.G_main) + outdegree(app.G_main);
                    else
                        deg = degree(app.G_main);
                    end
                    keyColName = char(tbl.Properties.VariableNames{1});
                    tblMap = containers.Map(cellstr(string(tbl.(keyColName))), num2cell(1:height(tbl)));
                    fullDeg = zeros(height(tbl), 1);
                    for i = 1:N
                        k = char(nodeList(i));
                        if isKey(tblMap, k)
                            fullDeg(tblMap(k)) = deg(i);
                        end
                    end
                    tbl.Degree = fullDeg;
                    tbl.Size = fullDeg;

                case 'Node Size'
                    if ~ismember("NodeSize", string(tbl.Properties.VariableNames))
                        return;
                    end
                    keyColName = char(tbl.Properties.VariableNames{1});
                    tblMap = containers.Map(cellstr(string(tbl.(keyColName))), num2cell(1:height(tbl)));
                    fullSize = zeros(height(tbl), 1);
                    for i = 1:N
                        k = char(nodeList(i));
                        if isKey(tblMap, k)
                            row = tblMap(k);
                            v = tbl.NodeSize(row);
                            if isnumeric(v) && isfinite(v) && v > 0
                                fullSize(row) = v;
                            end
                        end
                    end
                    tbl.Size = fullSize;
            end
        end

        % -----------------------------------------------------------------------------
        function G_filtered = applyGraphFilters(app)

            G_filtered = app.G_full;

            maxDeg    = round(app.MaxDegreeField.Value);
            minCompSz = round(app.MinCompSizeField.Value);

            % ------------------------------------------------------------
            % Filter 1: Remove high-degree nodes
            % maxDeg = 0 means no degree filtering
            % ------------------------------------------------------------
            if maxDeg > 0

                if isa(G_filtered, 'digraph')
                    deg = indegree(G_filtered) + outdegree(G_filtered);
                else
                    deg = degree(G_filtered);
                end

                keepNodes = G_filtered.Nodes.Name(deg <= maxDeg);
                G_filtered = subgraph(G_filtered, keepNodes);
            end

            % ------------------------------------------------------------
            % Filter 2: Remove small connected components
            % minCompSz = 1 means no component-size filtering
            % ------------------------------------------------------------
            if minCompSz > 1 && numnodes(G_filtered) > 0

                if isa(G_filtered, 'digraph')
                    A = adjacency(G_filtered);
                    G_und = graph(A | A');
                    G_und.Nodes.Name = G_filtered.Nodes.Name;
                else
                    G_und = G_filtered;
                end

                compID = conncomp(G_und);
                compSizes = accumarray(compID(:), 1);

                keepMask = compSizes(compID) >= minCompSz;
                keepNodes = G_filtered.Nodes.Name(keepMask);

                G_filtered = subgraph(G_filtered, keepNodes);
            end

            disp(sprintf('After filtering: %d nodes, %d edges remaining.', ...
                numnodes(G_filtered), numedges(G_filtered)));

        end

        % -----------------------------------------------------------------------------
        function fbaTable = runFBAIfNeeded(app)
            if ~isempty(app.fbaTable)
                fbaTable = app.fbaTable;
                return;
            end

            % ── Ensure an LP solver is available before calling optimizeCbModel ──
            global CBT_LP_SOLVER;
            if isempty(CBT_LP_SOLVER)
                try
                    changeCobraSolver('glpk', 'LP', 0); % 0 = silent, no console spam
                catch
                    error('FBA failed: No LP solver could be set. Run initCobraToolbox() in MATLAB first, or install GLPK.');
                end
            end
            % ─────────────────────────────────────────────────────────────────────

            disp('Running FBA...');
            try
                app.fbaTable = runFBA(app.model);
                fbaTable = app.fbaTable;
                disp('FBA complete.');
            catch ME
                error('FBA failed: %s', ME.message);
            end
        end

        % Value changed function: SizeDropDown
        function SizeDropDownValueChanged(app, event)
            if isempty(app.model) || isempty(app.G_main), return; end
            selected = app.SizeDropDown.Value;
            app.UIFigure.Pointer = 'watch';
            drawnow limitrate;
            try
                app.annoTable = computeColorGroups(app, app.ColorDropDown.Value);
                app.annoTable = computeSizeValues(app, selected);
                app.annoTable = applyPathwayHighlight(app, app.annoTable);
                labelColumn = getSelectedLabelColumn(app);
                app.annoTable = ensureLabelColumn(app, app.annoTable, labelColumn);
                writeNetworkHTML(app.G_main, app.annoTable, app.tempHTMLPath, labelColumn)
                app.HTMLViewer.HTMLSource = '';
                drawnow;
                app.HTMLViewer.HTMLSource = app.tempHTMLPath;
            catch ME
                uialert(app.UIFigure, ME.message, 'Error Applying Size');
            end
            app.UIFigure.Pointer = 'arrow';

        end

        % Button pushed function: ApplyFiltersButton
        function ApplyFiltersButtonPushed(app, event)
            if isempty(app.G_full) || isempty(app.annoTableFull)
                uialert(app.UIFigure, 'Load a model first.', 'No Data');
                return;
            end

            app.UIFigure.Pointer = 'watch';
            drawnow limitrate;

            try
                app.G_main = app.G_full;
                app.annoTable = app.annoTableFull;

                % Apply current filter settings to the full graph
                G_filtered = applyGraphFilters(app);

                if numnodes(G_filtered) == 0
                    uialert(app.UIFigure, ...
                        'All nodes were filtered out. Loosen your filter settings.', ...
                        'Empty Graph');
                    app.UIFigure.Pointer = 'arrow';
                    return;
                end

                % Keep only annotation rows for nodes remaining in filtered graph
                annoFiltered = app.annoTableFull;
                keyColName = char(annoFiltered.Properties.VariableNames{1});

                keepKeys = string(G_filtered.Nodes.Name);
                keepMask = ismember(string(annoFiltered.(keyColName)), keepKeys);
                annoFiltered = annoFiltered(keepMask, :);

                % Update displayed graph and annotation table
                app.G_main = G_filtered;
                app.annoTable = annoFiltered;

                % Refresh node dropdown to reflect currently displayed nodes
                populateNodeKeyDropDown(app);
                app.NodeKeyDropDown.Value = 'None';
                app.NodeKeyField.Value = 'None';

                % Recompute active pathway dropdown, color, size, highlight, and label state
                populatePathwayHighlightDropDown(app);

                app.annoTable = computeColorGroups(app, app.ColorDropDown.Value);
                app.annoTable = computeSizeValues(app, app.SizeDropDown.Value);
                app.annoTable = applyPathwayHighlight(app, app.annoTable);

                populateLabelByDropDown(app);
                labelColumn = getSelectedLabelColumn(app);
                app.annoTable = ensureLabelColumn(app, app.annoTable, labelColumn);

                % Render filtered graph
                writeNetworkHTML(app.G_main, app.annoTable, app.tempHTMLPath, labelColumn);

                app.HTMLViewer.HTMLSource = '';
                drawnow;
                app.HTMLViewer.HTMLSource = app.tempHTMLPath;

            catch ME
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
                uialert(app.UIFigure, ME.message, 'Filter Error');
            end

            app.UIFigure.Pointer = 'arrow';
        end

        % Button pushed function: ResetFiltersButton
        function ResetFiltersButtonPushed(app, event)

            if isempty(app.G_full) || isempty(app.annoTableFull)
                uialert(app.UIFigure, 'No full network is available.', 'No Data');
                return;
            end

            app.MaxDegreeField.Value    = 0;
            app.MinCompSizeField.Value  = 1;

            % Restore full graph and full annotation table
            app.G_main = app.G_full;
            app.annoTable = app.annoTableFull;

            % Recompute active color/size/highlight on full graph
            populatePathwayHighlightDropDown(app);

            app.annoTable = computeColorGroups(app, app.ColorDropDown.Value);
            app.annoTable = computeSizeValues(app, app.SizeDropDown.Value);
            app.annoTable = applyPathwayHighlight(app, app.annoTable);

            labelColumn = getSelectedLabelColumn(app);
            app.annoTable = ensureLabelColumn(app, app.annoTable, labelColumn);
            writeNetworkHTML(app.G_main, app.annoTable, app.tempHTMLPath, labelColumn)

            app.HTMLViewer.HTMLSource = '';
            drawnow;
            app.HTMLViewer.HTMLSource = app.tempHTMLPath;
        end

        function maybeWarnNoPathwayInfo(app, tbl, selected)
            if selected ~= "Pathway"
                return;
            end

            if ~ismember("ColorGroup", string(tbl.Properties.VariableNames))
                return;
            end

            cg = string(tbl.ColorGroup);
            cg = strtrim(cg);

            if isempty(cg)
                return;
            end

            allUnassigned = all(cg == "" | cg == "Unassigned");

            if allUnassigned
                uialert(app.UIFigure, ...
                    'Pathway information is not provided for the currently displayed nodes. All shown nodes are labeled "Unassigned".', ...
                    'No Pathway Information');
            end
        end

        function displayName = formatMetNodeDisplay(app, nodeKey)
            % Convert metabolite IDs like:
            %   glc__D_c  -> glc__D [c]
            %   nh4_e     -> nh4 [e]
            %   atp[m]    -> atp [m]
            % Leave non-metabolite or unrecognized keys unchanged.

            s = string(nodeKey);

            % Case 1: bracket form, e.g. atp[c]
            tok = regexp(s, '^(.*)\[([A-Za-z0-9_]+)\]$', 'tokens', 'once');
            if ~isempty(tok)
                core = string(tok{1});
                comp = string(tok{2});
                displayName = core + " [" + comp + "]";
                return;
            end

            % Case 2: underscore suffix form, e.g. atp_c or glc__D_e
            tok = regexp(s, '^(.*)_([A-Za-z0-9]+)$', 'tokens', 'once');
            if ~isempty(tok)
                core = string(tok{1});
                comp = string(tok{2});
                displayName = core + " [" + comp + "]";
                return;
            end

            % Fallback
            displayName = s;
        end

        function tbl = ensureLabelColumn(app, tbl, labelColumn)

    labelColumn = string(labelColumn);

    if labelColumn == "None" || labelColumn == "NodeID"
        return;
    end

    vars = string(tbl.Properties.VariableNames);

    if ismember(labelColumn, vars)
        return;
    end

    nodeList = string(app.G_main.Nodes.Name);
    keyColName = char(tbl.Properties.VariableNames{1});
    tblKeys = string(tbl.(keyColName));
    tblMap = containers.Map(cellstr(tblKeys), num2cell(1:height(tbl)));

    switch labelColumn

        case "Reversibility"

            vals = repmat("Unassigned", height(tbl), 1);

            if app.NetworkTypeButtonGroup.SelectedObject ~= app.RxnNetworkButton
                tbl.Reversibility = vals;
                return;
            end

            rxnMap = containers.Map(cellstr(string(app.model.rxns)), ...
                num2cell(1:length(app.model.rxns)));

            for i = 1:numel(nodeList)
                k = char(nodeList(i));

                if isKey(rxnMap, k) && isKey(tblMap, k)
                    idx = rxnMap(k);
                    lb = app.model.lb(idx);
                    ub = app.model.ub(idx);

                    if lb < 0 && ub > 0
                        vals(tblMap(k)) = "Reversible";
                    elseif ub <= 0
                        vals(tblMap(k)) = "Reverse Only";
                    else
                        vals(tblMap(k)) = "Irreversible";
                    end
                end
            end

            tbl.Reversibility = vals;

        case "GeneAssociation"

            vals = repmat("Unassigned", height(tbl), 1);

            if app.NetworkTypeButtonGroup.SelectedObject ~= app.RxnNetworkButton
                tbl.GeneAssociation = vals;
                return;
            end

            rxnMap = containers.Map(cellstr(string(app.model.rxns)), ...
                num2cell(1:length(app.model.rxns)));

            for i = 1:numel(nodeList)
                k = char(nodeList(i));

                if isKey(rxnMap, k) && isKey(tblMap, k)
                    idx = rxnMap(k);
                    hasGene = false;

                    if isfield(app.model,'grRules') && ...
                            numel(app.model.grRules) >= idx && ...
                            ~isempty(strtrim(app.model.grRules{idx}))
                        hasGene = true;

                    elseif isfield(app.model,'rules') && ...
                            numel(app.model.rules) >= idx && ...
                            ~isempty(strtrim(app.model.rules{idx}))
                        hasGene = true;

                    elseif isfield(app.model,'rxnGeneMat') && ...
                            size(app.model.rxnGeneMat,1) >= idx && ...
                            any(app.model.rxnGeneMat(idx,:))
                        hasGene = true;
                    end

                    if hasGene
                        vals(tblMap(k)) = "Gene-Associated";
                    else
                        vals(tblMap(k)) = "Spontaneous / Unknown";
                    end
                end
            end

            tbl.GeneAssociation = vals;

        case "MetaboliteClass"

            vals = repmat("Other", height(tbl), 1);

            if app.NetworkTypeButtonGroup.SelectedObject ~= app.MetNetworkButton
                tbl.MetaboliteClass = vals;
                return;
            end

            metMap = containers.Map(cellstr(string(app.model.mets)), ...
                num2cell(1:length(app.model.mets)));

            patterns = { ...
                'atp|adp|amp|nad|nadh|nadp|nadph|fad|fadh|gtp|gdp|coa', 'Cofactor / Energy carrier'; ...
                'ala|arg|asn|asp|cys|gln|glu|gly|his|ile|leu|lys|met|phe|pro|ser|thr|trp|tyr|val', 'Amino acid'; ...
                'glc|fru|gal|man|rib|xyl|suc|lac|pyr|pep|mal|fum|cit|oaa', 'Sugar / Organic acid'; ...
                'pc_|pe_|pg_|ps_|pi_|fa_|acyl|chol|lipid', 'Lipid'; ...
                'h2o|co2|o2|nh4|pi|ppi|h_', 'Inorganic / Ion' ...
                };

            for i = 1:numel(nodeList)
                k = char(nodeList(i));

                if isKey(metMap, k) && isKey(tblMap, k)
                    idx = metMap(k);
                    metID = lower(k);
                    metName = "";

                    if isfield(app.model,'metNames') && numel(app.model.metNames) >= idx
                        metName = lower(app.model.metNames{idx});
                    end

                    matched = false;

                    for p = 1:size(patterns, 1)
                        if ~isempty(regexp(metID, patterns{p,1}, 'once')) || ...
                                ~isempty(regexp(metName, patterns{p,1}, 'once'))
                            vals(tblMap(k)) = string(patterns{p,2});
                            matched = true;
                            break;
                        end
                    end

                    if ~matched
                        vals(tblMap(k)) = "Other";
                    end
                end
            end

            tbl.MetaboliteClass = vals;

        case "FBAFlux"

            if app.NetworkTypeButtonGroup.SelectedObject ~= app.RxnNetworkButton
                return;
            end

            fba = runFBAIfNeeded(app);
            cleanFluxKeys = strtrim(string(fba.ReactionKey));

            fbaVars = string(fba.Properties.VariableNames);
            if ismember("Flux", fbaVars)
                fbaVals = fba.Flux;
            elseif ismember("FBAFlux", fbaVars)
                fbaVals = fba.FBAFlux;
            else
                fbaVals = fba{:, 2};
            end

            fluxMap = containers.Map(cellstr(cleanFluxKeys), num2cell(fbaVals));

            vals = NaN(height(tbl), 1);

            for i = 1:numel(nodeList)
                k = char(strtrim(nodeList(i)));

                if isKey(fluxMap, k) && isKey(tblMap, k)
                    vals(tblMap(k)) = fluxMap(k);
                end
            end

            tbl.FBAFlux = vals;

    end

        end

        function populateLabelByDropDown(app)

            if isempty(app.G_main) || isempty(app.annoTable)
                app.LabelByDropDown.Items = {'None'};
                app.LabelByDropDown.Value = 'None';
                return;
            end

            vars = string(app.annoTable.Properties.VariableNames);

            if app.NetworkTypeButtonGroup.SelectedObject == app.RxnNetworkButton

                labelItems = {'None', 'Node ID'};

                if ismember("Name", vars)
                    labelItems{end+1} = 'Name';
                end
                if ismember("PathwayKey", vars)
                    labelItems{end+1} = 'Pathway';
                end
                if ismember("FormulaKey", vars)
                    labelItems{end+1} = 'Formula';
                end
                if ismember("Degree", vars)
                    labelItems{end+1} = 'Degree';
                end

                % These can be computed columns. Include them as valid reaction labels.
                labelItems{end+1} = 'Reversibility';
                labelItems{end+1} = 'Gene Association';

                if ismember("FBAFlux", vars) || ismember("FluxColor", vars)
                    labelItems{end+1} = 'FBA Flux';
                end

            else

                labelItems = {'None', 'Node ID'};

                if ismember("Name", vars)
                    labelItems{end+1} = 'Name';
                end
                if ismember("Compartment", vars)
                    labelItems{end+1} = 'Compartment';
                end
                if ismember("Degree", vars)
                    labelItems{end+1} = 'Degree';
                end

                % This can be computed from metabolite ID/name.
                labelItems{end+1} = 'Metabolite Class';

            end

            currentVal = app.LabelByDropDown.Value;

            app.LabelByDropDown.Items = labelItems;

            if isempty(currentVal) || ~ismember(currentVal, labelItems)
                app.LabelByDropDown.Value = 'None';
            else
                app.LabelByDropDown.Value = currentVal;
            end

        end

        function labelColumn = getSelectedLabelColumn(app)

            if isempty(app.LabelByDropDown) || isempty(app.LabelByDropDown.Value)
                labelColumn = "None";
                return;
            end

            selected = string(app.LabelByDropDown.Value);

            switch selected
                case "None"
                    labelColumn = "None";

                case "Node ID"
                    labelColumn = "NodeID";

                case "Name"
                    labelColumn = "Name";

                case "Pathway"
                    labelColumn = "PathwayKey";

                case "Formula"
                    labelColumn = "FormulaKey";

                case "Degree"
                    labelColumn = "Degree";

                case "Reversibility"
                    labelColumn = "Reversibility";

                case "Gene Association"
                    labelColumn = "GeneAssociation";

                case "FBA Flux"
                    labelColumn = "FBAFlux";

                case "Compartment"
                    labelColumn = "Compartment";

                case "Metabolite Class"
                    labelColumn = "MetaboliteClass";

                otherwise
                    labelColumn = "None";
            end

        end

        function populateNodeKeyDropDown(app)
            % Always initialize/reset map
            app.nodeDisplayMap = containers.Map('KeyType','char','ValueType','char');

            if isempty(app.G_full) || numnodes(app.G_full) == 0
                app.NodeKeyDropDown.Items = {'None'};
                app.NodeKeyDropDown.Value = 'None';
                app.NodeKeyField.Value = 'None';
                return;
            end

            nodeKeys = string(app.G_full.Nodes.Name);

            % Reaction network: display true keys directly
            if app.NetworkTypeButtonGroup.SelectedObject == app.RxnNetworkButton
                realItems = sort(cellstr(nodeKeys));
                items = [{'None'}; realItems(:)];

                for i = 1:numel(realItems)
                    app.nodeDisplayMap(realItems{i}) = realItems{i};
                end

                app.NodeKeyDropDown.Items = items;
                app.NodeKeyDropDown.Value = 'None';
                app.NodeKeyField.Value = 'None';
                return;
            end

            % Metabolite network: display "core_id [compartment]"
            displayItems = strings(numel(nodeKeys),1);
            for i = 1:numel(nodeKeys)
                dispName = formatMetNodeDisplay(app, nodeKeys(i));
                displayItems(i) = dispName;

                % If duplicates happen, fall back to true key display for that item
                if isKey(app.nodeDisplayMap, char(dispName))
                    dispName = nodeKeys(i);
                    displayItems(i) = dispName;
                end

                app.nodeDisplayMap(char(displayItems(i))) = char(nodeKeys(i));
            end

            realItems = sort(cellstr(displayItems));
            items = [{'None'}; realItems(:)];

            app.NodeKeyDropDown.Items = items;
            app.NodeKeyDropDown.Value = 'None';
            app.NodeKeyField.Value = 'None';
        end

        function populatePathwayHighlightDropDown(app)
            % Only meaningful for reaction networks
            if app.NetworkTypeButtonGroup.SelectedObject ~= app.RxnNetworkButton
                app.HighlightPathwayDropDown.Items = {'None'};
                app.HighlightPathwayDropDown.Value = 'None';
                app.HighlightPathwayDropDown.Enable = 'off';
                return;
            end

            if isempty(app.annoTable) || isempty(app.G_main) || numnodes(app.G_main) == 0
                app.HighlightPathwayDropDown.Items = {'None'};
                app.HighlightPathwayDropDown.Value = 'None';
                app.HighlightPathwayDropDown.Enable = 'off';
                return;
            end

            if ~ismember("PathwayKey", string(app.annoTable.Properties.VariableNames))
                app.HighlightPathwayDropDown.Items = {'None'};
                app.HighlightPathwayDropDown.Value = 'None';
                app.HighlightPathwayDropDown.Enable = 'off';
                return;
            end

            % Restrict pathway choices to reactions currently visible in G_main
            keyColName = string(app.annoTable.Properties.VariableNames{1});
            visibleKeys = string(app.G_main.Nodes.Name);
            visibleMask = ismember(string(app.annoTable.(keyColName)), visibleKeys);

            tblVisible = app.annoTable(visibleMask, :);

            if isempty(tblVisible) || height(tblVisible) == 0
                app.HighlightPathwayDropDown.Items = {'None'};
                app.HighlightPathwayDropDown.Value = 'None';
                app.HighlightPathwayDropDown.Enable = 'off';
                return;
            end

            p = string(tblVisible.PathwayKey);
            p = strtrim(p);
            p(ismissing(p) | p == "") = "Unassigned";
            p = unique(sort(p));

            pathwayItems = cellstr(p(:));

            items = cell(numel(pathwayItems) + 1, 1);
            items{1} = 'None';
            if ~isempty(pathwayItems)
                items(2:end) = pathwayItems;
            end

            app.HighlightPathwayDropDown.Items = items;
            app.HighlightPathwayDropDown.Enable = 'on';

            currentVal = app.HighlightPathwayDropDown.Value;
            if isempty(currentVal) || ~ismember(currentVal, items)
                app.HighlightPathwayDropDown.Value = 'None';
            end
        end

        function tbl = applyPathwayHighlight(app, tbl)
            % Remove stale highlight column first
            if ismember("HighlightFlag", string(tbl.Properties.VariableNames))
                tbl.HighlightFlag = [];
            end

            % Only apply on reaction network
            if app.NetworkTypeButtonGroup.SelectedObject ~= app.RxnNetworkButton
                return;
            end

            % Require pathway information
            if ~ismember("PathwayKey", string(tbl.Properties.VariableNames))
                return;
            end

            selectedPathway = string(app.HighlightPathwayDropDown.Value);
            if selectedPathway == "" || selectedPathway == "None"
                return;
            end

            keyColName = string(tbl.Properties.VariableNames{1});
            visibleKeys = string(app.G_main.Nodes.Name);
            visibleMask = ismember(string(tbl.(keyColName)), visibleKeys);

            p = string(tbl.PathwayKey);
            p = strtrim(p);
            p(ismissing(p) | p == "") = "Unassigned";

            hitMask = visibleMask & (p == selectedPathway);

            % If the selected pathway is not in the currently displayed graph,
            % do not activate highlight mode.
            if ~any(hitMask)
                tbl.HighlightFlag = true(height(tbl), 1);
                app.HighlightPathwayDropDown.Value = 'None';
                return;
            end

            % Visible selected-pathway nodes are highlighted; all others are dimmed
            tbl.HighlightFlag = hitMask;
        end

        function mergedTable = overlayFileMetadata(app, baseTable, fileTable)
            % Overlay file metadata onto a model-derived base table.
            % Matching is done by the first column of each table (key column).
            % For matching keys, non-empty file values override base values.
            % Unmatched base rows are retained. Extra file rows are ignored.

            mergedTable = baseTable;

            if isempty(fileTable) || height(fileTable) == 0
                return;
            end

            baseKeyName = string(mergedTable.Properties.VariableNames{1});
            fileKeyName = string(fileTable.Properties.VariableNames{1});

            baseKeys = string(mergedTable.(baseKeyName));
            fileKeys = string(fileTable.(fileKeyName));

            fileMap = containers.Map(cellstr(fileKeys), num2cell(1:height(fileTable)));

            fileVars = string(fileTable.Properties.VariableNames);
            baseVars = string(mergedTable.Properties.VariableNames);

            % Add any file columns not already present in baseTable
            for v = fileVars
                if ~ismember(v, baseVars)
                    if isstring(fileTable.(v)) || iscellstr(fileTable.(v)) || ischar(fileTable.(v))
                        mergedTable.(v) = repmat("", height(mergedTable), 1);
                    elseif isnumeric(fileTable.(v))
                        mergedTable.(v) = NaN(height(mergedTable), 1);   % ← was: missing(height(...),1)
                    else
                        mergedTable.(v) = NaN(height(mergedTable), 1);
                    end
                end
            end

            % Refresh variable list after adding columns
            baseVars = string(mergedTable.Properties.VariableNames);

            for i = 1:height(mergedTable)
                k = char(baseKeys(i));
                if isKey(fileMap, k)
                    r = fileMap(k);

                    for v = fileVars
                        if v == fileKeyName, continue; end
                        if ~ismember(v, baseVars), continue; end

                        fileValue = fileTable.(v)(r, :);

                        if isstring(fileValue)
                            if ~ismissing(fileValue) && strlength(strtrim(fileValue)) > 0
                                mergedTable.(v)(i, :) = fileValue;
                            end
                        else
                            % FIX: removed try/catch; NaN initialization makes this safe
                            if ~ismissing(fileValue)
                                mergedTable.(v)(i, :) = fileValue;
                            end
                        end
                    end
                end
            end
        end

        function centerNode = getRequestedCenterNode(app)
            typedNode = strtrim(string(app.NodeKeyField.Value));
            selectedNode = strtrim(string(app.NodeKeyDropDown.Value));

            % If no node is selected
            if typedNode == "" || typedNode == "None"
                typedNode = "";
            end

            if selectedNode == "" || selectedNode == "None"
                selectedNode = "";
            end

            if typedNode ~= ""
                centerNode = typedNode;
            elseif selectedNode ~= ""
                centerNode = selectedNode;
            else
                centerNode = "";
            end
        end

        function pathwayMap = getVisibleReactionPathwayMap(app)
            % Build a map: reaction key -> pathway key for the currently available annotation table.
            % Only meaningful for reaction networks.

            pathwayMap = containers.Map('KeyType','char','ValueType','char');

            if isempty(app.annoTable)
                return;
            end

            vars = string(app.annoTable.Properties.VariableNames);
            if ~ismember("PathwayKey", vars)
                return;
            end

            keyColName = string(app.annoTable.Properties.VariableNames{1});
            keys = string(app.annoTable.(keyColName));
            pths = string(app.annoTable.PathwayKey);

            for i = 1:height(app.annoTable)
                k = strtrim(char(keys(i)));
                p = strtrim(char(pths(i)));

                if isempty(k)
                    continue;
                end
                if isempty(p)
                    p = 'Unassigned';
                end

                pathwayMap(k) = p;
            end
        end

        function seedNodes = getSeedNodesFromSelectedPathway(app)
            % Uses the currently selected pathway in HighlightPathwayDropDown
            % and returns all visible reaction nodes in G_full that belong to it.

            seedNodes = strings(0,1);

            if isempty(app.G_full) || isempty(app.annoTableFull)
                error('No full network is available.');
            end

            if app.NetworkTypeButtonGroup.SelectedObject ~= app.RxnNetworkButton
                error('Pathway-restricted hop is only available for the reaction network.');
            end

            if ~ismember("PathwayKey", string(app.annoTableFull.Properties.VariableNames))
                error('No PathwayKey information is available.');
            end

            selectedPathway = string(app.HighlightPathwayDropDown.Value);
            if selectedPathway == "" || selectedPathway == "None"
                error('Please select a pathway in "Highlight Pathway" first.');
            end

            keyColName = string(app.annoTableFull.Properties.VariableNames{1});
            tblKeys = string(app.annoTableFull.(keyColName));
            p = string(app.annoTableFull.PathwayKey);

            p = strtrim(p);
            p(ismissing(p) | p == "") = "Unassigned";

            seedMask = (p == selectedPathway);
            seedNodes = tblKeys(seedMask);

            % Keep only nodes that are actually present in the full displayed graph
            seedNodes = seedNodes(ismember(seedNodes, string(app.G_full.Nodes.Name)));
            seedNodes = unique(seedNodes, 'stable');

            if isempty(seedNodes)
                error('No nodes from pathway "%s" are present in the current full graph.', selectedPathway);
            end
        end

        function [G_sub, annoSub] = getNeighborhoodSubgraph(app, centerNode, k)
            % Two modes:
            %   Graph Hop:
            %       k-hop neighborhood around a single center node.
            %
            %   Pathway-Restricted Hop:
            %       Ignore center node. Use all nodes in the selected pathway
            %       (from HighlightPathwayDropDown) as seed nodes, and keep all
            %       nodes within k hops of ANY seed node.

            if isempty(app.G_full) || isempty(app.annoTableFull)
                error('No full network is available.');
            end

            nodeNames = string(app.G_full.Nodes.Name);
            modeVal = string(app.NeighborhoodModeDropDown.Value);

            % Build undirected topology for hop computation
            if isa(app.G_full, 'digraph')
                A = adjacency(app.G_full);
                G_topology = graph(A | A');
                G_topology.Nodes.Name = app.G_full.Nodes.Name;
            else
                G_topology = app.G_full;
            end

            if modeVal == "Pathway-Restricted Hop"
                % Ignore centerNode completely
                seedNodes = getSeedNodesFromSelectedPathway(app);

                % Distances from all seed nodes to all graph nodes
                D = distances(G_topology, seedNodes, nodeNames);

                % If only one seed node, distances may come back as a vector
                if isvector(D)
                    minD = D(:);
                else
                    minD = min(D, [], 1)';
                end

                keepMask = isfinite(minD) & (minD <= k);
                keepNodes = nodeNames(keepMask);

            else
                % Standard node-centered graph hop
                if ~any(nodeNames == centerNode)
                    error('Node "%s" was not found in the current full network.', centerNode);
                end

                d = distances(G_topology, centerNode, nodeNames);
                keepMask = isfinite(d) & d <= k;
                keepNodes = nodeNames(keepMask);
            end

            if isempty(keepNodes)
                error('No nodes were found for the selected neighborhood settings.');
            end

            G_sub = subgraph(app.G_full, keepNodes);

            keyColName = char(app.annoTableFull.Properties.VariableNames{1});
            annoKeys = string(app.annoTableFull.(keyColName));
            annoMask = ismember(annoKeys, keepNodes);
            annoSub = app.annoTableFull(annoMask, :);

            % Reorder annotation rows to match keepNodes order
            [~, loc] = ismember(keepNodes, annoKeys(annoMask));
            validLoc = loc(loc > 0);
            if ~isempty(validLoc)
                annoSub = annoSub(validLoc, :);
            end
        end

        % Button pushed function: ShowNeighborhoodButton
        function ShowNeighborhoodButtonPushed2(app, event)
            if isempty(app.G_full) || isempty(app.annoTableFull)
                uialert(app.UIFigure, 'Load a model first.', 'No Data');
                return;
            end

            modeVal = string(app.NeighborhoodModeDropDown.Value);
            k = str2double(app.NeighborhoodDepthDropDown.Value);

            % Default: no center node needed
            centerNode = "";

            if modeVal == "Pathway-Restricted Hop"
                if app.NetworkTypeButtonGroup.SelectedObject ~= app.RxnNetworkButton
                    uialert(app.UIFigure, ...
                        'Pathway-restricted hop is only available for the reaction network.', ...
                        'Unavailable Mode');
                    return;
                end

                selectedPathway = string(app.HighlightPathwayDropDown.Value);
                if selectedPathway == "" || selectedPathway == "None"
                    uialert(app.UIFigure, ...
                        'Please select a pathway first in "Highlight Pathway".', ...
                        'Missing Pathway');
                    return;
                end

            else
                % Graph Hop mode still uses node key
                centerNode = getRequestedCenterNode(app);
                if centerNode == ""
                    uialert(app.UIFigure, ...
                        'Please type a node key or select one from the list.', ...
                        'Missing Node ID');
                    return;
                end
            end

            app.UIFigure.Pointer = 'watch';
            drawnow limitrate;

            try
                [G_sub, annoSub] = getNeighborhoodSubgraph(app, centerNode, k);

                if numnodes(G_sub) == 0
                    uialert(app.UIFigure, 'No neighborhood nodes were found.', 'Empty Neighborhood');
                    app.UIFigure.Pointer = 'arrow';
                    return;
                end

                app.G_main = G_sub;
                app.annoTable = annoSub;

                % Recompute active color/size on the returned subgraph
                app.annoTable = computeColorGroups(app, app.ColorDropDown.Value);
                app.annoTable = computeSizeValues(app, app.SizeDropDown.Value);
                app.annoTable = applyPathwayHighlight(app, app.annoTable);

                labelColumn = getSelectedLabelColumn(app);
                app.annoTable = ensureLabelColumn(app, app.annoTable, labelColumn);
                writeNetworkHTML(app.G_main, app.annoTable, app.tempHTMLPath, labelColumn)
                app.HTMLViewer.HTMLSource = '';
                drawnow;
                app.HTMLViewer.HTMLSource = app.tempHTMLPath;

            catch ME
                uialert(app.UIFigure, ME.message, 'Neighborhood Error');
            end

            app.UIFigure.Pointer = 'arrow';
        end

        % Button pushed function: BacktoFullNetworkButton
        function BacktoFullNetworkButtonPushed(app, event)
            if isempty(app.G_full) || isempty(app.annoTableFull)
                uialert(app.UIFigure, 'No full network is available.', 'No Data');
                return;
            end

            app.UIFigure.Pointer = 'watch';
            drawnow limitrate;

            try
                % Restore full graph and full annotation table
                app.G_main = app.G_full;
                app.annoTable = app.annoTableFull;

                % Reset node selection controls
                populateNodeKeyDropDown(app);
                app.NodeKeyDropDown.Value = 'None';
                app.NodeKeyField.Value = 'None';

                % Reset pathway highlight controls
                populatePathwayHighlightDropDown(app);
                if any(strcmp(app.HighlightPathwayDropDown.Items, 'None'))
                    app.HighlightPathwayDropDown.Value = 'None';
                end

                % Optional: reset neighborhood mode back to ordinary graph-hop mode
                if any(strcmp(app.NeighborhoodModeDropDown.Items, 'Graph Hop'))
                    app.NeighborhoodModeDropDown.Value = 'Graph Hop';
                end

                % Recompute active color/size after restoring the full graph
                app.annoTable = computeColorGroups(app, app.ColorDropDown.Value);
                app.annoTable = computeSizeValues(app, app.SizeDropDown.Value);

                % This now does nothing if Highlight Pathway = None,
                % and it also removes stale HighlightFlag if present.
                app.annoTable = applyPathwayHighlight(app, app.annoTable);

                % Render full, un-highlighted network
                labelColumn = getSelectedLabelColumn(app);
                app.annoTable = ensureLabelColumn(app, app.annoTable, labelColumn);
                writeNetworkHTML(app.G_main, app.annoTable, app.tempHTMLPath, labelColumn)

                app.HTMLViewer.HTMLSource = '';
                drawnow;
                app.HTMLViewer.HTMLSource = app.tempHTMLPath;

            catch ME
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
                uialert(app.UIFigure, ME.message, 'Back to Full Network Error');
            end

            app.UIFigure.Pointer = 'arrow';

        end

        % Value changed function: NodeKeyDropDown
        function NodeKeyDropDownValueChanged(app, event)
            selectedDisplay = strtrim(string(app.NodeKeyDropDown.Value));

            if selectedDisplay == "" || selectedDisplay == "None"
                app.NodeKeyField.Value = 'None';
                return;
            end

            if ~isempty(app.nodeDisplayMap) && isKey(app.nodeDisplayMap, char(selectedDisplay))
                trueKey = string(app.nodeDisplayMap(char(selectedDisplay)));
                app.NodeKeyField.Value = char(trueKey);
            else
                app.NodeKeyField.Value = char(selectedDisplay);
            end
        end

        % Value changed function: HighlightPathwayDropDown
        function HighlightPathwayDropDownValueChanged(app, event)
            if isempty(app.model) || isempty(app.G_main)
                return;
            end

            app.UIFigure.Pointer = 'watch';
            drawnow limitrate;

            try
                app.annoTable = computeColorGroups(app, app.ColorDropDown.Value);
                app.annoTable = computeSizeValues(app, app.SizeDropDown.Value);
                app.annoTable = applyPathwayHighlight(app, app.annoTable);

                labelColumn = getSelectedLabelColumn(app);
                app.annoTable = ensureLabelColumn(app, app.annoTable, labelColumn);
                writeNetworkHTML(app.G_main, app.annoTable, app.tempHTMLPath, labelColumn)

                app.HTMLViewer.HTMLSource = '';
                drawnow;
                app.HTMLViewer.HTMLSource = app.tempHTMLPath;

            catch ME
                uialert(app.UIFigure, ME.message, 'Error Applying Pathway Highlight');
            end

            app.UIFigure.Pointer = 'arrow';

        end

        % Value changed function: LabelByDropDown
        function LabelByDropDownValueChanged(app, event)
            if isempty(app.G_main)
                return;
            end

            app.UIFigure.Pointer = 'watch';
            drawnow limitrate;

            try
                labelColumn = getSelectedLabelColumn(app);
                app.annoTable = ensureLabelColumn(app, app.annoTable, labelColumn);

                writeNetworkHTML(app.G_main, app.annoTable, app.tempHTMLPath, labelColumn);

                app.HTMLViewer.HTMLSource = 'about:blank';
                drawnow;
                app.HTMLViewer.HTMLSource = app.tempHTMLPath;

            catch ME
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
                uialert(app.UIFigure, ME.message, 'Error Updating Labels');
            end

            app.UIFigure.Pointer = 'arrow';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RightAxes = uiaxes(app.GridLayout);
            title(app.RightAxes, 'Title')
            xlabel(app.RightAxes, 'X')
            ylabel(app.RightAxes, 'Y')
            zlabel(app.RightAxes, 'Z')
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadModelButton = uibutton(app.LeftPanel, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load BiGG Model';
            app.SizeByDropDownLabel = uilabel(app.LeftPanel);
            app.SizeByDropDownLabel.Text = 'Size By';
            app.ColorByDropDownLabel = uilabel(app.LeftPanel);
            app.ColorByDropDownLabel.Text = 'Color By';
            app.ColorDropDown = uidropdown(app.LeftPanel);
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.SizeDropDown = uidropdown(app.LeftPanel);
            app.SizeDropDown.ValueChangedFcn = createCallbackFcn(app, @SizeDropDownValueChanged, true);
            app.LabelByDropDownLabel = uilabel(app.LeftPanel);
            app.LabelByDropDownLabel.Text = 'Label By';
            app.MaxDegree0offEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxDegree0offEditFieldLabel.Text = 'Max Degree (0 = off)';
            app.MaxDegreeField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxDegreeField.Limits = [0 Inf];
            app.MinComponentSizeEditFieldLabel = uilabel(app.LeftPanel);
            app.MinComponentSizeEditFieldLabel.Text = 'Min Component Size';
            app.MinCompSizeField = uieditfield(app.LeftPanel, 'numeric');
            app.MinCompSizeField.Limits = [1 Inf];
            app.MinCompSizeField.Value = 1;
            app.ApplyFiltersButton = uibutton(app.LeftPanel, 'push');
            app.ApplyFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFiltersButtonPushed, true);
            app.ApplyFiltersButton.Text = 'Apply Filters';
            app.ResetFiltersButton = uibutton(app.LeftPanel, 'push');
            app.ResetFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFiltersButtonPushed, true);
            app.ResetFiltersButton.Text = 'Reset Filters';
            app.NetworkTypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.NetworkTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NetworkTypeButtonGroupSelectionChanged, true);
            app.NetworkTypeButtonGroup.Title = 'Network Type';
            app.RxnNetworkButton = uitogglebutton(app.NetworkTypeButtonGroup);
            app.RxnNetworkButton.Text = 'Reaction Network';
            app.RxnNetworkButton.Value = true;
            app.MetNetworkButton = uitogglebutton(app.NetworkTypeButtonGroup);
            app.MetNetworkButton.Text = 'Metabolite Network';
            app.LabelByDropDown = uidropdown(app.LeftPanel);
            app.LabelByDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelByDropDownValueChanged, true);
            app.TypeNodeKeyLabel = uilabel(app.LeftPanel);
            app.TypeNodeKeyLabel.Text = 'Type Node Key';
            app.NodeKeyField = uieditfield(app.LeftPanel, 'text');
            app.NeighborhoodDepthDropDownLabel = uilabel(app.LeftPanel);
            app.NeighborhoodDepthDropDownLabel.Text = 'Neighborhood Depth';
            app.NeighborhoodDepthDropDown = uidropdown(app.LeftPanel);
            app.NeighborhoodDepthDropDown.Items = {'1', '2', '3'};
            app.NeighborhoodDepthDropDown.Value = '1';
            app.ShowNeighborhoodButton = uibutton(app.LeftPanel, 'push');
            app.ShowNeighborhoodButton.ButtonPushedFcn = createCallbackFcn(app, @ShowNeighborhoodButtonPushed2, true);
            app.ShowNeighborhoodButton.Text = 'Show Neighborhood';
            app.BacktoFullNetworkButton = uibutton(app.LeftPanel, 'push');
            app.BacktoFullNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoFullNetworkButtonPushed, true);
            app.BacktoFullNetworkButton.Text = 'Back to Full Network';
            app.SelectNodeKeyDropDownLabel = uilabel(app.LeftPanel);
            app.SelectNodeKeyDropDownLabel.Text = 'Select Node Key';
            app.NodeKeyDropDown = uidropdown(app.LeftPanel);
            app.NodeKeyDropDown.ValueChangedFcn = createCallbackFcn(app, @NodeKeyDropDownValueChanged, true);
            app.HighlightPathwayDropDownLabel = uilabel(app.LeftPanel);
            app.HighlightPathwayDropDownLabel.Text = 'Highlight Pathway';
            app.HighlightPathwayDropDown = uidropdown(app.LeftPanel);
            app.HighlightPathwayDropDown.ValueChangedFcn = createCallbackFcn(app, @HighlightPathwayDropDownValueChanged, true);
            app.NeighborhoodModeDropDownLabel = uilabel(app.LeftPanel);
            app.NeighborhoodModeDropDownLabel.Text = 'Neighborhood Mode';
            app.NeighborhoodModeDropDown = uidropdown(app.LeftPanel);
            app.NeighborhoodModeDropDown.Items = {'Graph Hop', 'Pathway-Restricted Hop'};
            app.NeighborhoodModeDropDown.Value = 'Graph Hop';
            app.HTMLViewer = uihtml(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BiGGViz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end